classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        InputqueryimagesButton   matlab.ui.control.Button
        SwitchLabel              matlab.ui.control.Label
        Switch                   matlab.ui.control.Switch
        SimilarityDropDownLabel  matlab.ui.control.Label
        SimilarityDropDown       matlab.ui.control.DropDown
        UIAxes                   matlab.ui.control.UIAxes
    end


    methods (Access = private)

        % Button pushed function: InputqueryimagesButton
        function uploadQueryImage(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.gif;*.bmp', 'All Image Files (*.jpg, *.png, *.gif, *.bmp)'; ...
                '*.*',                   'All Files (*.*)'}, ...
                'Pick an image file', 'MultiSelect', 'on');
            image = imread([pathname,filename]);
     
            imshow(image, 'Parent', app.UIAxes);
            
        end

        % Callback function
        function SimilarityDropDownValueChanged(app, event)
app.SimilarityDropDown            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.InputqueryimagesButton = uibutton(app.UIFigure, 'push');
            app.InputqueryimagesButton.ButtonPushedFcn = createCallbackFcn(app, @uploadQueryImage, true);
            app.InputqueryimagesButton.Text = 'Input query images';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.SimilarityDropDownLabel = uilabel(app.UIFigure);
            app.SimilarityDropDownLabel.Text = 'Similarity';
            app.SimilarityDropDown = uidropdown(app.UIFigure);
            app.SimilarityDropDown.Items = {'1', '0.9', '0.8', '0', '0.6', '0.5', '0.4', '0.3', '0.2', '0.1'};
            app.SimilarityDropDown.Value = '1';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end